classdef gropro_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadButton                      matlab.ui.control.Button
        PassDataEditField               matlab.ui.control.EditField
        GetFrameButton                  matlab.ui.control.Button
        DataEditField                   matlab.ui.control.EditField
        DataLabel                       matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        TrackingLamp                    matlab.ui.control.Lamp
        TrackingLampLabel               matlab.ui.control.Label
        InitiatingLamp                  matlab.ui.control.Lamp
        InitiatingLampLabel             matlab.ui.control.Label
        SearchingLamp                   matlab.ui.control.Lamp
        SearchingLampLabel              matlab.ui.control.Label
        VideoEditField                  matlab.ui.control.EditField
        VideoEditFieldLabel             matlab.ui.control.Label
        PauseButton                     matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        TimestampEditField              matlab.ui.control.NumericEditField
        TimestampEditFieldLabel         matlab.ui.control.Label
        DetectionConfidenceThresholdEditField  matlab.ui.control.NumericEditField
        DetectionConfidenceThresholdEditFieldLabel  matlab.ui.control.Label
        BoxSizeErrorThresholdEditField  matlab.ui.control.NumericEditField
        BoxSizeErrorThresholdEditFieldLabel  matlab.ui.control.Label
        AveragingWindowSizeEditField    matlab.ui.control.NumericEditField
        AveragingWindowSizeEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel          matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Paused % boolean to allow for pausing of the video
        Get % boolean to let app know when to update paused img
        Video
        Detector
        Sensor
        Pause
        TrackedPos
        PassingDistances
    end
    
    methods (Access = private)
        
        function ProcessVideo(app)
            if ~exist("app.Video", "var")
                app.Video = VideoReader(app.VideoEditField.Value);
            end
            
            % init car detection model
            if ~exist("app.Detector", "var")
                % Sensor configuration
                focalLength    = [988.4268, 1005.4];
                principalPoint = [956.4559, 540.2352];
                imageSize      = [1080 1920];
                height = 0.968;
                pitch = 0;   
                camIntrinsics = cameraIntrinsics(focalLength, principalPoint, imageSize);
                sensor        = monoCamera(camIntrinsics, height, 'Pitch', pitch);
                app.Sensor = sensor;
        
                % Width of a common vehicle is between 1.5 to 2.5 meters
                vehicleWidth = [1.5, 2.5];
                app.Detector = configureDetectorMonoCamera(vehicleDetectorFasterRCNN(), sensor, vehicleWidth);
            end
    
            app.Pause = false;
            x1 = 0;

            %keep track of wether waiting for cars to track (0), beginning to
            %track a new car (1), or tracking a car (2).
            trackingState = 0;
            
            %number of boxes added to the average when beggining tracking
            bcount = 0;
            
            %smoothed avg area of bboxes in the tracked car
            avgsize = 0;

            %number of frames which a tracked car can produce no accurate bboxes
            %until it is forgotten by the tracker
            ttl = 7;
            %number of consecutive frames which the tracked car was not detected
            absence = 0;

            %init lamps
            updateLamps(app, 1, 0, 0);

            app.UIAxes2.XLim = [0, 25];
            app.UIAxes2.YLim = [0, 10];

            % check video for cars
            while app.TimestampEditField.Value < app.Video.Duration
                if app.Pause
                    return;
                end

                app.Video.CurrentTime = app.TimestampEditField.Value;
                frame = app.Video.readFrame;
                imshow(frame, 'Parent', app.UIAxes);
            
                [bboxes, scores] = detect(app.Detector, frame);
                
                %if multiple boxes, find location of largest one
                boxidx = largestbox(app,bboxes);

                %check if the score is acceptable
                if boxidx <= 0 || scores(boxidx) < app.DetectionConfidenceThresholdEditField 
                    app.TimestampEditField.Value = app.TimestampEditField.Value + 2 * app.EditField.Value;
                    continue;
                end

                locations = computeVehicleLocations(app, bboxes(boxidx, :));
                if locations(1) > 25
                    app.TimestampEditField.Value = app.TimestampEditField.Value + app.EditField.Value;
                    continue;
                end

                trackingState = 1;
                skip = false;
                avgsize = 0;
                bcount = 0;
                absence = 0;
                updateLamps(app, 0, 1, 0); 
                app.TrackedPos = [];

                while trackingState > 0
                    %skip if previously updated with low confidence prediction
                    if skip
                        [trackingState, absence, skip, locations] = updateEverything(app, trackingState, absence, ttl, skip);
                        continue;
                    end
            
                    disp("tracking...")
            
                    db = doubleBox(app, bboxes, boxidx);
                    if db > -1
                        bboxes(boxidx,1) = (bboxes(boxidx,1) + bboxes(db,1))/2;
                        bboxes(boxidx,2) = (bboxes(boxidx,2) + bboxes(db,2))/2;
                        bboxes(boxidx,3) = (bboxes(boxidx,3) + bboxes(db,3))/2;
                        bboxes(boxidx,4) = (bboxes(boxidx,4) + bboxes(db,4))/2;
                    end
                    
                    x = bboxes(boxidx,1);
                    y = bboxes(boxidx,2);
                    area = bboxes(boxidx,3) * bboxes(boxidx,4);

                    %initialize tracking
                    if trackingState == 1
                        %TODO: keep track of distance behind camera
                        if bcount == 0
                            x1 = locations(1);
                        end

                        bcount = bcount + 1;
                        absence = 0;
                        avgsize = avgsize + area;

                        if bcount == app.AveragingWindowSizeEditField.Value
                            %make sure car is moving towards camera
                            if x1 < locations(1)
                                break;
                            end
                            trackingState = 2;
                            avgsize = avgsize / app.AveragingWindowSizeEditField.Value;
                            avgdistance = (x1 + locations(1)) / 2;
                            updateLamps(app, 0, 0, 1);
                        end
                        app.TrackedPos = [app.TrackedPos; [area, locations(1), locations(2)]];
                    end
                
                    %check if box is an acceptable size (width might be a better
                    %heuristic since distance is determined by width, I believe)
                    if trackingState == 2
                        %TODO: MAKE THIS DECREASE STEP SIZE FROM .5s to
                        %.08s linearly from 25-10m back then for 5-8 decrease it to .05 maybe 
                        if abs(area - avgsize)/avgsize < app.BoxSizeErrorThresholdEditField.Value & abs(locations(1) - avgdistance)/avgdistance < .3
                            absence = 0;
                            avgsize = mean(app.TrackedPos(size(app.TrackedPos, 1)-app.AveragingWindowSizeEditField.Value:size(app.TrackedPos, 1), 1));
                            avgdistance = rollingavg(app, avgdistance, locations(1));
                            %if locations(1) > 10
                                %app.TimestampEditField.Value = app.TimestampEditField.Value + (.5 - app.StepSizeEditField.Value) * (locations(1) - 10)/15;
                            %end
                        else
                            absence = absence + 1;
                            if absence == ttl
                                trackingState = 0;
                                app.PassingDistances = [app.PassingDistances; [mean(app.TrackedPos(app.TrackedPos(:, 2) < 12 & app.TrackedPos(:, 2) > 5, 3)), app.TimestampEditField.Value]];
                                app.TimestampEditField.Value = app.TimestampEditField.Value + app.EditField.Value;
                            end
                        end
                    end
            
                    scatter(app.UIAxes2, locations(1), abs(locations(2)));
                    hold(app.UIAxes2, "on")
                    app.TrackedPos = [app.TrackedPos; [area, locations(1), locations(2)]];
                    [trackingState, absence, skip, locations] = updateEverything(app, trackingState, absence, ttl, skip);
                    if app.Pause
                        return;
                    end
                end
                hold(app.UIAxes2, "off")
            end
        end


        function [trackingState, absence, skip, locations] = updateEverything(app, trackingState, absence, ttl, skip)
            %update frame number
            app.TimestampEditField.Value = app.TimestampEditField.Value + app.StepSizeEditField.Value;
            app.Video.CurrentTime = app.TimestampEditField.Value;
            frame = app.Video.readFrame;

            [bboxes, scores] = detect(app.Detector, frame);
            locations = computeVehicleLocations(app, bboxes);
            frame = insertVehicleDetections(app, frame, locations, bboxes);
            imshow(frame, 'Parent', app.UIAxes);

            boxidx = largestbox(app, bboxes);
            
            if boxidx <= 0 || scores(boxidx) < app.DetectionConfidenceThresholdEditField.Value || isempty(bboxes) || locations(1) > 25
                absence = absence + 1;
                if absence >= ttl
                    trackingState = 0;
                    updateLamps(app, 1, 0, 0);
                end
                skip = true;
            else
                skip = false;
            end
        end

        function i = doubleBox(bboxes, boxidx)
            for i = 1:size(bboxes, 1)
                if bboxes(i, 1) > bboxes(boxidx, 1) & bboxes(i, 1) < bboxes(boxidx, 1) + bboxes(boxidx, 3)
                    if bboxes(i, 2) > bboxes(boxidx, 2) & bboxes(i, 2) < bboxes(boxidx, 2) + bboxes(boxidx, 4)
                        return;
                    end
                end
            end
        end

        function app = updateLamps(app, l1, l2, l3)
            if l1 == 1
                app.SearchingLamp.Color = [0 1 0];
            else
                app.SearchingLamp.Color = [0 0 0];
            end

            if l2 == 1
                app.InitiatingLamp.Color = [0 1 0];
            else
                app.InitiatingLamp.Color = [0 0 0];
            end

            if l3 == 1
                app.TrackingLamp.Color = [0 1 0];
            else
                app.TrackingLamp.Color = [0 0 0];
            end
        end

        function locations = computeVehicleLocations(bboxes)

        locations = zeros(size(bboxes,1),2);
            for i = 1:size(bboxes, 1)
                bbox  = bboxes(i, :);
                
                % Get [x,y] location of the center of the lower portion of the
                % detection bounding box in meters. bbox is [x, y, width, height] in
                % image coordinates, where [x,y] represents upper-left corner.
                yBottom = bbox(2) + bbox(4) - 1;
                xCenter = bbox(1) + (bbox(3)-1)/15; % approximate center
                
                locations(i,:) = imageToVehicle(app.Sensor, [xCenter, yBottom]);
            end
        end
        
        function imgOut = insertVehicleDetections(app, imgIn, locations, bboxes)
            imgOut = imgIn;
            
            for i = 1:size(locations, 1)
                location = locations(i, :);
                bbox     = bboxes(i, :);
                    
                label = sprintf('X=%0.2f, Y=%0.2f', location(1), location(2));
            
                imgOut = insertObjectAnnotation(imgOut, ...
                    'rectangle', bbox, label, 'AnnotationColor','g');
            end
        end
        
        function avg = rollingavg(app, avg, sample)
            avg = (app.AveragingWindowSizeEditField.Value - 1) * avg;
            avg = avg + sample;
            avg = avg / app.AveragingWindowSizeEditField.Value;
        end    
        
        function idx = largestbox(app,bboxes)
            maxsize = 0;
            idx = -1;
            for i = 1:size(bboxes, 1)
                area = bboxes(i,3) * bboxes(i,4);
                if area > maxsize
                    maxsize = area;
                    idx = i;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function ProcessButtonPushed(app, event)
            if event.Source.Text == "Play"
                ProcessVideo(app);
            elseif event.Source.Text == "Pause"
                app.Paused = true;
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.Pause = true;
        end

        % Button pushed function: SaveDataButton
        function SaveButtonPushed(app, event)
            writematrix(app.PassingDistances, "data.txt");
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            m = readmatrix(app.DataEditField.Value);

            M = [];
            row = m(1,:);
            for i = 2:size(m,1) 
                if m(i,2) == 0
                    col = zeros(size(row, 1)-1, 1) + row(1, 1);
                    row(1,:) = [];
                    M = [M; row(:, 1), col, row(:, 2:end)];
                    row = m(i,:);
                else
                    row = [row; m(i, :)];
                end
            end
            
            M = sortrows(M);
                
            video = VideoReader(app.VideoEditField.Value);
            app.Video = video;
            video.CurrentTime = M(1,1); 
            endtime = max(M(1,:));

            function imgOut = insertColoredVehicleDetections(imgIn, locations, bboxes)
                colors = ["r", "g", "b", "c", "m", "y"];
                imgOut = imgIn;
                
                for ii = 1:size(locations, 1)
                    location = locations(ii, :);
                    bbox     = bboxes(ii, :);
                        
                    label = sprintf('X=%0.2f, Y=%0.2f', location(1), location(2));
                
                    imgOut = insertObjectAnnotation(imgOut, ...
                        'rectangle', bbox, label, 'AnnotationColor',colors(mod(location(1), length(colors)) +  1));
                end
            end

            si = 1;
            i = 0;
            while video.CurrentTime < endtime
                if app.Pause
                    video.currentTime = app.TimestampEditField.Value;
                    if app.Get 
                        bboxes = M(M(:,1) == app.TimestampEditField.Value);
                        labels = M(M(:,1) == app.TimestampEditField.Value, [2,4]);
                        %draw all bboxes
                        f = insertColoredVehicleDetections(f, labels, bboxes);
                        imshow(f, 'Parent', app.UIAxes);
                        app.Get = false;
                    end
                    continue;
                end
                i = i + 1;
                if video.CurrentTime ~= M(i, 1)
                    f = video.readFrame;

                    bboxes = M(si:i-1, end-3:end);
                    labels = M(si:i-1, [2,4]);
                    %draw all bboxes
                    f = insertColoredVehicleDetections(f, labels, bboxes);
                    imshow(f, 'Parent', app.UIAxes);

                    si = i;
                    video.CurrentTime = M(i, 1);
                    app.TimestampEditField.Value = video.CurrentTime;
                end
            end
            
        end

        % Button pushed function: GetFrameButton
        function GetFrameButtonPushed(app, event)
            app.Get = true;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %load passing data and display it on plot
            M = readmatrix(app.PassDataEditField.Value);
            colors = ["r", "g", "b", "c", "m", "y"];
            endtime = max(M(1,:));

            lastTime = -1;
            while app.Video.CurrentTime < endtime
                if app.Video.CurrentTime == lastTime
                    continue;
                end
                lastTime = app.Video.CurrentTime;
                vehicles = M(M(:, 1) == app.Video.CurrentTime, :);
                if isempty(vehicles)
                    hold(app.UIAxes2, "off")
                else
                    for i = 1:size(vehicles, 1)
                        scatter(app.UIAxes2, vehicles(i,end-1), abs(vehicles(i,end)), [], colors(mod(vehicles(i,2), 6)+1));
                        hold(app.UIAxes2, "on")
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'PY vs. PX')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 5 10 15 20 25];
            app.UIAxes2.XTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'};
            app.UIAxes2.YTick = [0 2 4 6 8 10];
            app.UIAxes2.YTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'};
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeEditField.Value = 0.08;
            app.AveragingWindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.AveragingWindowSizeEditFieldLabel.Text = 'Averaging Window Size';
            app.AveragingWindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragingWindowSizeEditField.Value = 5;
            app.BoxSizeErrorThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.BoxSizeErrorThresholdEditFieldLabel.Text = 'Box Size Error Threshold';
            app.BoxSizeErrorThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.BoxSizeErrorThresholdEditField.Value = 0.5;
            app.DetectionConfidenceThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.DetectionConfidenceThresholdEditFieldLabel.Text = 'Detection Confidence Threshold';
            app.DetectionConfidenceThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DetectionConfidenceThresholdEditField.Value = 0.55;
            app.TimestampEditFieldLabel = uilabel(app.UIFigure);
            app.TimestampEditFieldLabel.Text = 'Timestamp';
            app.TimestampEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimestampEditField.Value = 40;
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.VideoEditFieldLabel = uilabel(app.UIFigure);
            app.VideoEditFieldLabel.Text = 'Video:';
            app.VideoEditField = uieditfield(app.UIFigure, 'text');
            app.VideoEditField.Value = 'GX010062.MP4';
            app.SearchingLampLabel = uilabel(app.UIFigure);
            app.SearchingLampLabel.Text = 'Searching';
            app.SearchingLamp = uilamp(app.UIFigure);
            app.InitiatingLampLabel = uilabel(app.UIFigure);
            app.InitiatingLampLabel.Text = 'Initiating';
            app.InitiatingLamp = uilamp(app.UIFigure);
            app.TrackingLampLabel = uilabel(app.UIFigure);
            app.TrackingLampLabel.Text = 'Tracking';
            app.TrackingLamp = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1.5;
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DataLabel = uilabel(app.UIFigure);
            app.DataLabel.Text = 'Data:';
            app.DataEditField = uieditfield(app.UIFigure, 'text');
            app.DataEditField.Value = 'FullData.txt';
            app.GetFrameButton = uibutton(app.UIFigure, 'push');
            app.GetFrameButton.ButtonPushedFcn = createCallbackFcn(app, @GetFrameButtonPushed, true);
            app.GetFrameButton.Text = 'Get Frame';
            app.PassDataEditField = uieditfield(app.UIFigure, 'text');
            app.PassDataEditField.Value = 'PassData.txt';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gropro_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end